classdef app1_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoundEditorUIFigure            matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Track1Panel                    matlab.ui.container.Panel
        PlayButtonTrack1               matlab.ui.control.Button
        volumeGaugeLabel               matlab.ui.control.Label
        volumeGaugeTrack1              matlab.ui.control.LinearGauge
        EditFieldVolumeTrack1          matlab.ui.control.NumericEditField
        volumeadjustLabel              matlab.ui.control.Label
        volumeKnobTrack1               matlab.ui.control.Knob
        PauseButtonTrack1              matlab.ui.control.Button
        StopButtonTrack1               matlab.ui.control.Button
        LampTrack1                     matlab.ui.control.Lamp
        MainPanel                      matlab.ui.container.Panel
        Browse                         matlab.ui.control.Button
        LoadedSoundsListBoxLabel       matlab.ui.control.Label
        LoadedSoundsListBox            matlab.ui.control.ListBox
        LoadTrack1                     matlab.ui.control.Button
        LoadTrack2                     matlab.ui.control.Button
        PlayButtonBoth                 matlab.ui.control.Button
        PauseButtonBoth                matlab.ui.control.Button
        StopButtonBoth                 matlab.ui.control.Button
        Track2Panel_2                  matlab.ui.container.Panel
        PlayButtonTrack2               matlab.ui.control.Button
        volumeadjustLabel_2            matlab.ui.control.Label
        volumeKnobTrack2               matlab.ui.control.Knob
        PauseButtonTrack2              matlab.ui.control.Button
        StopButtonTrack2               matlab.ui.control.Button
        volumeGaugeLabel_2             matlab.ui.control.Label
        volumeGaugeTrack2              matlab.ui.control.LinearGauge
        EditFieldVolumeTrack2          matlab.ui.control.NumericEditField
        LampTrack2                     matlab.ui.control.Lamp
        RightPanel                     matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        Track1Panel_2                  matlab.ui.container.Panel
        UIAxesTrack1                   matlab.ui.control.UIAxes
        OffsetSliderLabel              matlab.ui.control.Label
        OffsetSliderTrack1             matlab.ui.control.Slider
        Track2Panel                    matlab.ui.container.Panel
        UIAxesTrack2                   matlab.ui.control.UIAxes
        OffsetSliderLabel_2            matlab.ui.control.Label
        OffsetSliderTrack2             matlab.ui.control.Slider
        BothPanel_2                    matlab.ui.container.Panel
        UIAxesTrackBoth                matlab.ui.control.UIAxes
        CuttingTab                     matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        Track1Panel_4                  matlab.ui.container.Panel
        UIAxesTrack1_Cut               matlab.ui.control.UIAxes
        StartSpinnerLabel              matlab.ui.control.Label
        CutStartSpinnerTrack1          matlab.ui.control.Spinner
        EndLabel                       matlab.ui.control.Label
        CutEndSpinnerTrack1            matlab.ui.control.Spinner
        CutButtonTrack1                matlab.ui.control.Button
        Track2Panel_4                  matlab.ui.container.Panel
        UIAxesTrack2_Cut               matlab.ui.control.UIAxes
        CutButtonTrack2                matlab.ui.control.Button
        StartSpinnerLabel_2            matlab.ui.control.Label
        CutStartSpinnerTrack2          matlab.ui.control.Spinner
        EndLabel_2                     matlab.ui.control.Label
        CutEndSpinnerTrack2            matlab.ui.control.Spinner
        BothPanel_3                    matlab.ui.container.Panel
        UIAxesTrackBoth_Cut            matlab.ui.control.UIAxes
        StartSpinnerLabel_3            matlab.ui.control.Label
        CutStartSpinnerTrackBoth       matlab.ui.control.Spinner
        EndLabel_3                     matlab.ui.control.Label
        CutEndSpinnerTrackBoth         matlab.ui.control.Spinner
        CutButtonTrackBoth             matlab.ui.control.Button
        EqualiserTab                   matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        ControlsPanel                  matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        HzSliderLabel                  matlab.ui.control.Label
        HzSlider                       matlab.ui.control.Slider
        HzLabel                        matlab.ui.control.Label
        HzSlider_2                     matlab.ui.control.Slider
        HzLabel_2                      matlab.ui.control.Label
        HzSlider_3                     matlab.ui.control.Slider
        HzLabel_3                      matlab.ui.control.Label
        HzSlider_4                     matlab.ui.control.Slider
        KHzSliderLabel                 matlab.ui.control.Label
        KHzSlider                      matlab.ui.control.Slider
        KHzSlider_2Label               matlab.ui.control.Label
        KHzSlider_2                    matlab.ui.control.Slider
        KHzSlider_3Label               matlab.ui.control.Label
        KHzSlider_3                    matlab.ui.control.Slider
        KHzSlider_4Label               matlab.ui.control.Label
        KHzSlider_4                    matlab.ui.control.Slider
        KHzSlider_5Label               matlab.ui.control.Label
        KHzSlider_5                    matlab.ui.control.Slider
        KHzSlider_6Label               matlab.ui.control.Label
        KHzSlider_6                    matlab.ui.control.Slider
        GraphPanel                     matlab.ui.container.Panel
        UIAxesEqualiser                matlab.ui.control.UIAxes
        EqualiseTrack1Button           matlab.ui.control.Button
        EqualiseTrack2Button           matlab.ui.control.Button
        EqualiseTrackBothButton        matlab.ui.control.Button
        SpectrumButton                 matlab.ui.control.Button
        SpectrumButton_2               matlab.ui.control.Button
        SpectrumButton_3               matlab.ui.control.Button
        UIAxesEqualiser_2              matlab.ui.control.UIAxes
        VolumeSliderLabel              matlab.ui.control.Label
        VolumeSlider                   matlab.ui.control.Slider
        ResetButton                    matlab.ui.control.Button
        RecordTab                      matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        RecordingoptionsPanel          matlab.ui.container.Panel
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        BitspersampleDropDownLabel     matlab.ui.control.Label
        BitspersampleDropDown          matlab.ui.control.DropDown
        NumberOfchannelsDropDownLabel  matlab.ui.control.Label
        NumberOfchannelsDropDown       matlab.ui.control.DropDown
        GetDeviceInfoButton            matlab.ui.control.Button
        UITableDeviceInfo              matlab.ui.control.Table
        Panel_2                        matlab.ui.container.Panel
        StartRecordingButton           matlab.ui.control.Button
        PlayRecordingButton            matlab.ui.control.Button
        EndRecordingButton             matlab.ui.control.Button
        LampRecording                  matlab.ui.control.Lamp
        RecordedAudioPanel             matlab.ui.container.Panel
        UIAxesRecorded                 matlab.ui.control.UIAxes
        ApplyToTrack1Button            matlab.ui.control.Button
        ApplyToTrack2Button            matlab.ui.control.Button
        ExportTab                      matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        SettingsPanel                  matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        Track1Panel_5                  matlab.ui.container.Panel
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameTrack1EditField        matlab.ui.control.EditField
        CurrentSamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        CurrentSamplingFrequencyTrack1EditField  matlab.ui.control.NumericEditField
        NewSamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        NewSamplingFrequencyEditTrack1Field  matlab.ui.control.NumericEditField
        ResampleTrack1Button           matlab.ui.control.Button
        ExportTrack1Button             matlab.ui.control.Button
        Track2Panel_5                  matlab.ui.container.Panel
        ResampleTrack2Button           matlab.ui.control.Button
        ExportTrack2Button             matlab.ui.control.Button
        FilenameEditFieldLabel_2       matlab.ui.control.Label
        FilenameTrack2EditField        matlab.ui.control.EditField
        CurrentSamplingFrequencyEditFieldLabel_2  matlab.ui.control.Label
        CurrentSamplingFrequencyTrack2EditField  matlab.ui.control.NumericEditField
        NewSamplingFrequencyEditFieldLabel_2  matlab.ui.control.Label
        NewSamplingFrequencyEditTrack2Field  matlab.ui.control.NumericEditField
        BothPanel_4                    matlab.ui.container.Panel
        ResampleTrackBothButton        matlab.ui.control.Button
        ExportTrackBothButton          matlab.ui.control.Button
        FilenameEditFieldLabel_3       matlab.ui.control.Label
        FilenameTrackBothEditField     matlab.ui.control.EditField
        CurrentSamplingFrequencyEditFieldLabel_3  matlab.ui.control.Label
        CurrentSamplingFrequencyTrackBothEditField  matlab.ui.control.NumericEditField
        NewSamplingFrequencyEditFieldLabel_3  matlab.ui.control.Label
        NewSamplingFrequencyEditTrackBothField  matlab.ui.control.NumericEditField
        HelpTab                        matlab.ui.container.Tab
        HelpPanel                      matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        ListBoxHelp                    matlab.ui.control.ListBox
        Panel_5                        matlab.ui.container.Panel
        HTML                           matlab.ui.control.HTML
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        track1_y % y data of track1
        track1_fs %fs of track1
        track1 % track1 title
        playerTrack1 % audioplayer object
        track2_y % track2 y data
        track2_fs % track 2 samplerate
        track2 % track 2 title
        playerTrack2 % audiplayer object
        trackBoth_y % data of both tracks merged
        trackBoth_fs %fs of both tracks
        playerTrackBoth %player of merged tracks
        PlayLine1 % The value of the scrolling play line for track 1
        PlayLine2 % The value of the scrolling play line for track 2
        PlayLineBoth %The value of the scrolling play line for merged tracks
        CutLineStart_1 % The value of the cutline Track 1
        CutLineEnd_1 % The value of the cutline Track 1
        CutStartPoint_1 % The value of the cut point track 1
        CutEndPoint_1 % The value of cut end point 1
        CutLineStart_2 % The value of the cutline Track 2
        CutLineEnd_2 % The value of the cutline Track 2
        CutStartPoint_2 % The value of the cut point track 2
        CutEndPoint_2 % The value of cut end point both
        CutLineStart_Both % The value of the cutline Track both
        CutLineEnd_Both % The value of the cutline Track both
        CutStartPoint_Both % The value of the cut point track both
        CutEndPoint_Both % The value of cut end point both
        Recording % Description
        EndRecording % Description
        RecordedPlayer % Description
        Track1Paused % Description
        Track2Paused % Description
        TrackBothPaused % Description
        TocTrack1 % Description
        TicTrack1 % Description
        ElapsedPauseTrack1 % Description
        TocTrack2 % Description
        TicTrack2 % Description
        ElapsedPauseTrack2 % Description
        TocTrackBoth % Description
        TicTrackBoth % Description
        ElapsedPauseTrackBoth % Description
    end
    
    
    methods (Access = private)
        
        %       Function to draw the plot, depending on the specidfied track
        function reDrawPlot(app, Track)
            switch Track
                case 1
                    t = trackTime(app, 1);
                    plot(app.UIAxesTrack1, t, app.track1_y); title(app.UIAxesTrack1, app.track1);
                    app.PlayLine1=line(app.UIAxesTrack1, [0,0],[min(app.track1_y(:,1)),max(app.track1_y(:,1))],'color','r','marker','o','linewidth',2);
                    plot(app.UIAxesTrack1_Cut, t, app.track1_y); title(app.UIAxesTrack1_Cut, app.track1);
                    app.CutLineStart_1=line(app.UIAxesTrack1_Cut, [0,0],[min(app.track1_y(:,1)),max(app.track1_y(:,1))],'color','r','marker','o','linewidth',2);
                    app.CutLineEnd_1=line(app.UIAxesTrack1_Cut, t(end)*[1,1],[min(app.track1_y(:,1)),max(app.track1_y(:,1))],'color','r','marker','o','linewidth',2);
                    app.CutEndSpinnerTrack1.Limits = [0 t(end)];
                    app.CutEndSpinnerTrack1.Value = t(end);
                    
                    
                case 2
                    t = trackTime(app, 2);
                    plot(app.UIAxesTrack2, t, app.track2_y); title(app.UIAxesTrack2, app.track2);
                    app.PlayLine2=line(app.UIAxesTrack2, [0,0],[min(app.track2_y(:,1)),max(app.track2_y(:,1))],'color','r','marker','o','linewidth',2);
                    plot(app.UIAxesTrack2_Cut, t, app.track2_y); title(app.UIAxesTrack2_Cut, app.track2);
                    app.CutLineStart_2=line(app.UIAxesTrack2_Cut, [0,0],[min(app.track2_y(:,1)),max(app.track2_y(:,1))],'color','r','marker','o','linewidth',2);
                    app.CutLineEnd_2=line(app.UIAxesTrack2_Cut, t(end)*[1,1],[min(app.track2_y(:,1)),max(app.track2_y(:,1))],'color','r','marker','o','linewidth',2);
                    app.CutEndSpinnerTrack2.Limits = [0 t(end)];
                    app.CutEndSpinnerTrack2.Value = t(end);
                case 3
                    t = trackTime(app, 3);
                    plot(app.UIAxesTrackBoth, t, app.trackBoth_y); title(app.UIAxesTrackBoth, 'Both');
                    app.PlayLineBoth=line(app.UIAxesTrackBoth, [0,0],[min(app.trackBoth_y(:,1)),max(app.trackBoth_y(:,1))],'color','r','marker','o','linewidth',2);
                    plot(app.UIAxesTrackBoth_Cut, t, app.trackBoth_y); title(app.UIAxesTrackBoth_Cut, 'Both');
                    app.CutLineStart_Both=line(app.UIAxesTrackBoth_Cut, [0,0],[min(app.trackBoth_y(:,1)),max(app.trackBoth_y(:,1))],'color','r','marker','o','linewidth',2);
                    app.CutLineEnd_Both=line(app.UIAxesTrackBoth_Cut, t(end)*[1,1],[min(app.trackBoth_y(:,1)),max(app.trackBoth_y(:,1))],'color','r','marker','o','linewidth',2);
                    app.CutEndSpinnerTrackBoth.Limits = [0 t(end)];
                    app.CutEndSpinnerTrackBoth.Value = t(end);
            end
            
        end
        
        %       Function to return an array of track times, as many calculations in the app depend on this.
        function t = trackTime(app, Track)
            switch Track
                case 1
                    dt = 1/app.track1_fs;
                    t = 0:dt:(length(app.track1_y)*dt)-dt;
                case 2
                    dt = 1/app.track2_fs;
                    t = 0:dt:(length(app.track2_y)*dt)-dt;
                case 3
                    dt = 1/app.trackBoth_fs;
                    t = 0:dt:(length(app.trackBoth_y)*dt)-dt;
            end
        end
        
        function SPL_dB = loudness(app, ~)
            Pa_ref = 20e-6;
            SPL_dB = 10*log10( ( get(app.playerTrack1, 'CurrentSample')/Pa_ref).^2);
        end
        
        %       take the input, find the padding size needed and make tracks the same length
        function padAndResize(app, sizeT1, sizeT2, colsT1, colsT2)
            if sizeT1 > sizeT2
                padSize = sizeT1-sizeT2;
                if colsT2 >1
                    pad= zeros(padSize,2);
                    app.track2_y = [app.track2_y; pad];
                else
                    pad= zeros(padSize,1);
                    app.track2_y = [app.track2_y; pad];
                end
            else
                padSize = sizeT2-sizeT1;
                if colsT1 >1
                    pad= zeros(padSize,2);
                    app.track1_y = [app.track1_y; pad];
                else
                    pad= zeros(padSize,1);
                    app.track1_y = [app.track1_y; pad];
                end
            end
        end
        
        function merge(app, track1_fs, track2_fs)
            fs = track1_fs;
            if track1_fs > track2_fs
                app.track1_y = resample(app.track1_y, track2_fs, track1_fs);
                fs = app.track2_fs;
                app.track1_fs = fs;
            elseif track1_fs < track2_fs
                app.track2_y = resample(app.track2_y, app.track1_fs, app.track2_fs);
                fs = app.track1_fs;
                app.track2_fs = fs;
            end
            [m,n] = size(app.track1_y);
            colsT1 = n;
            sizeT1 = m;
            
            [m,n] = size(app.track2_y);
            colsT2 = n;
            sizeT2 = m;
            
            
            %               if the tracks are different lengths then we need to make them the same length
            padAndResize(app, sizeT1, sizeT2, colsT1, colsT2);
            app.trackBoth_y = (app.track1_y + app.track2_y)/2;
            app.trackBoth_fs = fs;
            app.playerTrackBoth = audioplayer(app.trackBoth_y, fs);
        end
        
        
        function equalise(app, track,track_fs, track_y)
            hz1 = get(app.HzSlider, 'Value');
            hz2 = get(app.HzSlider_2, 'Value');
            hz3 = get(app.HzSlider_3, 'Value');
            hz4 = get(app.HzSlider_4, 'Value');
            hz5 = get(app.KHzSlider, 'Value');
            hz6 = get(app.KHzSlider_2, 'Value');
            hz7 = get(app.KHzSlider_3, 'Value');
            hz8 = get(app.KHzSlider_4, 'Value');
            hz9 = get(app.KHzSlider_5, 'Value');
            hz10 = get(app.KHzSlider_6, 'Value');
            
            cut_off=200;
            order=16;
            filt=fir1(order,cut_off/(track_fs/2), 'low');
            y1 = hz1*filter(filt,1,track_y);
            
            %bandpass
            freq1=201;
            freq2 = 400;
            b1=fir1(order,[freq1/(track_fs/2) freq2/(track_fs/2)],'bandpass');
            y2=hz2*filter(b1,1,track_y);
            
            
            freq3=401;
            freq4 = 800;
            b2=fir1(order,[freq3/(track_fs/2) freq4/(track_fs/2)],'bandpass');
            y3=hz3*filter(b2,1,track_y);
            
            
            freq4=801;
            freq5 = 1500;
            b3=fir1(order,[freq4/(track_fs/2) freq5/(track_fs/2)],'bandpass');
            y4=hz4*filter(b3,1,track_y);
            
            freq5=1501;
            freq6 = 3000;
            b4=fir1(order,[freq5/(track_fs/2) freq6/(track_fs/2)],'bandpass');
            y5=hz5*filter(b4,1,track_y);
            
            freq7=3001;
            freq8 = 5000;
            b5=fir1(order,[freq7/(track_fs/2) freq8/(track_fs/2)],'bandpass');
            y6=hz6*filter(b5,1,track_y);
            
            freq9=5001;
            freq10 = 7000;
            b6=fir1(order,[freq9/(track_fs/2) freq10/(track_fs/2)],'bandpass');
            y7=hz7*filter(b6,1,track_y);
            
            freq10=7001;
            freq11 = 10000;
            b7=fir1(order,[freq10/(track_fs/2) freq11/(track_fs/2)],'bandpass');
            y8=hz8*filter(b7,1,track_y);
            
            freq12=10001;
            freq13 = 15000;
            b8=fir1(order,[freq12/(track_fs/2) freq13/(track_fs/2)],'bandpass');
            y9=hz9*filter(b8,1,track_y);
            
            %highpass
            cut_off_2=15000;
            filt2=fir1(order,cut_off_2/(track_fs/2), 'high');
            y10=hz10*filter(filt2,1,track_y);
            
            vol = get(app.VolumeSlider, 'Value');
            allY=vol*(y1+y2+y3+y4+y5+y6+y7+y8+y9+y10);
            
            switch track
                case 1
                    app.track1_y = allY;
                    ydft = fft(app.track1_y);
                    ydft = ydft(1:length(app.track1_y)/2+1);
                    freq = 0:app.track1_fs/length(app.track1_y):app.track1_fs/2;
                    plot(app.UIAxesEqualiser_2, freq, abs(ydft));
                    plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
                    reDrawPlot(app, 1);
                case 2
                    app.track2_y = allY;
                    ydft = fft(app.track2_y);
                    ydft = ydft(1:length(app.track2_y)/2+1);
                    freq = 0:app.track2_fs/length(app.track2_y):app.track2_fs/2;
                    plot(app.UIAxesEqualiser_2, freq, abs(ydft));
                    plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
                    reDrawPlot(app, 2);
                    
                case 3
                    app.trackBoth_y = allY;
                    ydft = fft(app.trackBoth_y);
                    ydft = ydft(1:length(app.trackBoth_y)/2+1);
                    freq = 0:app.trackBoth_fs/length(app.trackBoth_y):app.trackBoth_fs/2;
                    plot(app.UIAxesEqualiser_2, freq, abs(ydft));
                    plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
                    reDrawPlot(app, 3);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Loop on every element in the folder and update the list
            help_folder = dir("help/");
            helpfiles = [];
            for i = 1:length(help_folder)
                if help_folder(i).isdir==0
                    helpfiles{end+1,1} = help_folder(i).name;
                end
            end
            addpath("help/");
            set(app.ListBoxHelp, 'Items', helpfiles);
            app.Track1Paused = false;
            app.Track2Paused = false;
            app.TrackBothPaused = false;
        end

        % Button pushed function: Browse
        function BrowsePushed(app, event)
            % get the folder
            folder_name = uigetdir;
            % get what is inside the folder
            Infolder = dir(folder_name);
            % Initialize the cell of string that will be update in the list box
            MyListOfFiles = [];
            % Loop on every element in the folder and update the list
            for i = 1:length(Infolder)
                if Infolder(i).isdir==0
                    MyListOfFiles{end+1,1} = Infolder(i).name;
                end
            end
            % update the listbox with the result
            set(app.LoadedSoundsListBox,'Items',MyListOfFiles)
            addpath(folder_name);


            %           Enable the load track buttons
            set(app.LoadTrack1, 'Enable', 'on');
            set(app.LoadTrack2, 'Enable', 'on');
            
        end

        % Button pushed function: LoadTrack1
        function LoadTrack1ButtonPushed(app, event)
            
            %           read the audio file from the selected list box
            [y,fs] = audioread(app.LoadedSoundsListBox.Value);
            
            %           assign globals so audio data can be accessed in other functions
            app.track1=app.LoadedSoundsListBox.Value;
            app.track1_y=y;
            app.track1_fs=fs;
            
            %           create an audio player for the loaded track
            app.playerTrack1 = audioplayer(y,fs);
            
            %           plot the track in time/amplitude.
            reDrawPlot(app, 1);
            ydft = fft(app.track1_y);
            ydft = ydft(1:length(app.track1_y)/2+1);
            freq = 0:app.track1_fs/length(app.track1_y):app.track1_fs/2;
            plot(app.UIAxesEqualiser_2, freq, abs(ydft));
            plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
            %           enable audio buttons, offset slider and display the track panel as active
            set(app.PlayButtonTrack1, 'Enable', 'on');
            set(app.OffsetSliderTrack1, 'Enable', 'on');
            set(app.LampTrack1, 'Enable', 'on');
            set(app.CurrentSamplingFrequencyTrack1EditField, 'Value', app.track1_fs);
            set(app.FilenameTrack1EditField, 'Value', app.track1);
        end

        % Button pushed function: LoadTrack2
        function LoadTrack2ButtonPushed(app, event)
            
            %           read the audio file from the selected list box
            [y,fs] = audioread(app.LoadedSoundsListBox.Value);
            
            %           assign globals so audio data can be accessed in other functions
            app.track2=app.LoadedSoundsListBox.Value;
            app.track2_y=y;
            app.track2_fs=fs;
            
            %           create an audio player for the loaded track
            app.playerTrack2 = audioplayer(y,fs);
            
            %           plot the track in time/amplitude
            reDrawPlot(app, 2);
            ydft = fft(app.track2_y);
            ydft = ydft(1:length(app.track2_y)/2+1);
            freq = 0:app.track2_fs/length(app.track2_y):app.track2_fs/2;
            plot(app.UIAxesEqualiser_2, freq, abs(ydft));
            plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
            %           enable audio buttons, offset slider and display the track panel as active
            set(app.PlayButtonTrack2, 'Enable', 'on');
            set(app.OffsetSliderTrack2, 'Enable', 'on');
            set(app.LampTrack2, 'Enable', 'on');
            set(app.CurrentSamplingFrequencyTrack2EditField, 'Value', app.track2_fs);
            set(app.FilenameTrack2EditField, 'Value', app.track2);
        end

        % Button pushed function: PauseButtonBoth
        function PauseButtonBothPushed(app, event)
            
            pause(app.playerTrackBoth);
            app.TrackBothPaused = true;
            app.ElapsedPauseTrackBoth=tic;
            
        end

        % Button pushed function: PauseButtonTrack1
        function PauseButtonTrack1Pushed(app, event)
            pause(app.playerTrack1);
            app.Track1Paused = true;
            app.ElapsedPauseTrack1=tic;
        end

        % Button pushed function: PauseButtonTrack2
        function PauseButtonTrack2Pushed(app, event)
            pause(app.playerTrack2);
            app.Track2Paused = true;
            app.ElapsedPauseTrack2=tic;
        end

        % Button pushed function: PlayButtonBoth
        function PlayButtonBothPushed(app, event)
            %      Check that both tracks are loaded
            if ~isempty(app.track1) && ~isempty(app.track2)
                
                %           Enable the play and pause buttons for the assigned player
                set(app.PauseButtonBoth, 'Enable', 'on');
                set(app.StopButtonBoth, 'Enable', 'on');
                
                
                %           start playback of the track
                if app.TrackBothPaused == false
                    merge(app, app.track1_fs, app.track2_fs);
                    reDrawPlot(app, 3);
                    end_time = length(app.trackBoth_y)/app.trackBoth_fs;
                    set(app.CurrentSamplingFrequencyTrackBothEditField, 'Value', app.trackBoth_fs);
                    app.playerTrackBoth = audioplayer(app.trackBoth_y,app.trackBoth_fs);
                    play(app.playerTrackBoth);
                    app.TicTrackBoth=tic;
                    app.TocTrackBoth=toc(app.TicTrackBoth);
                    elapsed=0;
                elseif app.TrackBothPaused == true
                    end_time = length(app.trackBoth_y)/app.trackBoth_fs;
                    resume(app.playerTrackBoth);
                    elapsed=toc(app.ElapsedPauseTrackBoth);
                    app.TrackBothPaused = false;
                end
                %           start a timer
                
                
                %           while the timer is less than the tack length
                while app.TocTrackBoth<end_time & app.TrackBothPaused == false
                    
                    %               draw the line on the plot a t
                    set(app.PlayLineBoth, 'xdata', app.TocTrackBoth*[1,1])
                    drawnow;
                    app.TocTrackBoth=toc(app.TicTrackBoth)-elapsed;
                end
                
                %           Reset
                if app.TocTrackBoth>=end_time
                    set(app.PlayLineBoth, 'xdata', [0,0]);
                    set(app.PauseButtonBoth, 'Enable', 'on');
                    set(app.StopButtonBoth, 'Enable', 'on');
                    set(app.CurrentSamplingFrequencyTrackBothEditField, 'Value',app.trackBoth_fs);
                end
            end


        end

        % Button pushed function: PlayButtonTrack1
        function PlayButtonTrack1Pushed(app, event)
            %           Enable the stop and pause buttons for the assigned player
            set(app.PauseButtonTrack1, 'Enable', 'on');
            set(app.StopButtonTrack1, 'Enable', 'on');
            
            
            %           calculate the time in seconds
            end_time = length(app.track1_y)/app.track1_fs;
            
            %           Reset the audioplayer to load sound changes.
            
            
            %           start playback of the track
            if app.Track1Paused == false
                app.playerTrack1 = audioplayer(app.track1_y,app.track1_fs);
                play(app.playerTrack1);
                app.TicTrack1=tic;
                app.TocTrack1=toc(app.TicTrack1);
                elapsed=0;
            elseif app.Track1Paused == true
                resume(app.playerTrack1);
                elapsed=toc(app.ElapsedPauseTrack1);
                app.Track1Paused = false;
            end
            
            
            %           start a timer
            %           while the timer is less than the tack length
            while app.TocTrack1<end_time & app.Track1Paused == false
                
                %               draw the line on the plot a t
                set(app.PlayLine1, 'xdata', app.TocTrack1*[1,1])
                drawnow;
                
                %               fallback variable
                lastGoodVal = -96;
                
                %               approximate the loudness of the track
                value = integratedLoudness(app.track1_y(get(app.playerTrack1, 'CurrentSample'):end,:),app.track1_fs);
                
                if ~isnan(value) & ~isempty(value)
                    set(app.volumeGaugeTrack1, 'Value', value);
                    set(app.EditFieldVolumeTrack1, 'Value', value);
                    lastGoodVal = value;
                else
                    
                    set(app.volumeGaugeTrack1, 'Value', lastGoodVal);
                end
                app.TocTrack1=toc(app.TicTrack1)-elapsed;
            end
            
            %           Reset
            if  app.TocTrack1>=end_time
                set(app.PlayLine1, 'xdata', [0,0]);
                set(app.volumeGaugeTrack1, 'Value', -96);
                set(app.EditFieldVolumeTrack1, 'Value', -96);
                set(app.PauseButtonTrack1, 'Enable', 'off');
                set(app.StopButtonTrack1, 'Enable', 'off');
            end
            
        end

        % Button pushed function: PlayButtonTrack2
        function PlayButtonTrack2Pushed(app, event)
            %           Enable the play and pause buttons for the assigned player
            set(app.PauseButtonTrack2, 'Enable', 'on');
            set(app.StopButtonTrack2, 'Enable', 'on');
            
            %           calculate the time in seconds
            end_time = length(app.track2_y)/app.track2_fs;
            
            %           start playback of the track
            if app.Track2Paused == false
                app.playerTrack2 = audioplayer(app.track2_y,app.track2_fs);
                play(app.playerTrack2);
                app.TicTrack2=tic;
                app.TocTrack2=toc(app.TicTrack2);
                elapsed=0;
            elseif app.Track2Paused == true
                resume(app.playerTrack2);
                elapsed=toc(app.ElapsedPauseTrack2);
                app.Track2Paused = false;
            end
            
            
            %           while the timer is less than the tack length
            while app.TocTrack2<end_time & app.Track2Paused == false
                
                %               draw the line on the plot a t
                set(app.PlayLine2, 'xdata', app.TocTrack2*[1,1])
                drawnow;
                
                %               fallback variable
                lastGoodVal = -96;
                
                %               approximate the loudness of the track
                value = integratedLoudness(app.track2_y(get(app.playerTrack2, 'CurrentSample'):end,:),app.track2_fs);
                
                if ~isnan(value) & ~isempty(value)
                    set(app.volumeGaugeTrack2, 'Value', value);
                    set(app.EditFieldVolumeTrack2, 'Value', value);
                    lastGoodVal = value;
                else
                    
                    set(app.volumeGaugeTrack2, 'Value', lastGoodVal);
                end
                
                app.TocTrack2=toc(app.TicTrack2)-elapsed;
            end
            
            %           Reset
            if  app.TocTrack2>=end_time
                set(app.PlayLine2, 'xdata', [0,0]);
                set(app.volumeGaugeTrack2, 'Value', -96);
                set(app.EditFieldVolumeTrack2, 'Value', -96);
                set(app.PauseButtonTrack2, 'Enable', 'off');
                set(app.StopButtonTrack2, 'Enable', 'off');
            end
        end

        % Button pushed function: StopButtonBoth
        function StopButtonBothPushed(app, event)
            
            stop(app.playerTrackBoth);
            set(app.PlayLineBoth, 'xdata', [0,0]);
        end

        % Button pushed function: StopButtonTrack1
        function StopButtonTrack1Pushed(app, event)
            stop(app.playerTrack1);
            set(app.PlayLine1, 'xdata', [0,0]);
        end

        % Button pushed function: StopButtonTrack2
        function StopButtonTrack2Pushed(app, event)
            stop(app.playerTrack2);
            set(app.PlayLine2, 'xdata', [0,0]);
        end

        % Value changed function: volumeKnobTrack1
        function volumeKnobTrack1ValueChanged(app, event)
            
            %           get the user set value
            volume = app.volumeKnobTrack1.Value;
            
            %           perform the calculation to increase/decrease the volume.
            app.track1_y = app.track1_y*volume;


            %           calculate the new graph and plot
            reDrawPlot(app, 1);
        end

        % Value changed function: volumeKnobTrack2
        function volumeKnobTrack2ValueChanged(app, event)
            %           get the user set value
            volume = app.volumeKnobTrack2.Value;
            
            %           perform the calculation to increase/decrease the volume.
            app.track2_y = app.track2_y*volume;
            
            %           calculate the new graph and plot
            reDrawPlot(app, 2);
        end

        % Value changed function: CutStartSpinnerTrack1
        function CutStartSpinnerTrack1ValueChanged(app, event)
            value = app.CutStartSpinnerTrack1.Value;
            if value < get(app.CutLineEnd_1, 'xdata')
                set(app.CutLineStart_1, 'xdata', value*[1,1]);
                drawnow;
                app.CutStartPoint_1 = value;
            end
            
        end

        % Value changed function: CutEndSpinnerTrack1
        function CutEndSpinnerTrack1ValueChanged(app, event)
            value = app.CutEndSpinnerTrack1.Value;
            if value > get(app.CutLineStart_1, 'xdata')
                app.CutEndPoint_1 = value;
                value = get(app.CutLineEnd_1, 'xdata') - value;
                set(app.CutLineEnd_1, 'xdata', get(app.CutLineEnd_1, 'xdata')-value);
                drawnow;
                
            end
        end

        % Button pushed function: CutButtonTrack1
        function CutButtonTrack1Pushed(app, event)
            t = trackTime(app, 1);
            [~,numCols] = size(app.track1_y);
            last = app.CutEndPoint_1;
            start = app.CutStartPoint_1;
            if numCols > 1
                [~,loc1] = min(abs(t-app.CutStartPoint_1));
                [~,locx] = min(abs(t-abs(app.CutEndPoint_1)));
                app.track1_y([1:loc1,locx:end],:)=[];
                reDrawPlot(app, 1);
                
                %                if the track is mono, then we want to create a 1 column vector to cut
            else
                [~,loc1] = min(abs(t-app.CutStartPoint_1));
                [~,locx] = min(abs(t-abs(app.CutEndPoint_1)));
                app.track1_y([1:loc1,locx:end])=[];
                reDrawPlot(app, 1);
            end
            app.playerTrack1 = audioplayer(app.track1_y, app.track1_fs);
        end

        % Value changed function: CutStartSpinnerTrack2
        function CutStartSpinnerTrack2ValueChanged(app, event)
            value = app.CutStartSpinnerTrack2.Value;
            if value < get(app.CutLineEnd_2, 'xdata')
                set(app.CutLineStart_2, 'xdata', value*[1,1]);
                drawnow;
                app.CutStartPoint_2 = value;
            end
        end

        % Value changed function: CutEndSpinnerTrack2
        function CutEndSpinnerTrack2ValueChanged(app, event)
            value = app.CutEndSpinnerTrack2.Value;
            if value > get(app.CutLineStart_2, 'xdata')
                app.CutEndPoint_2 = value;
                value = get(app.CutLineEnd_2, 'xdata') - value;
                set(app.CutLineEnd_2, 'xdata', get(app.CutLineEnd_2, 'xdata')-value);
                drawnow;
                
            end
        end

        % Button pushed function: CutButtonTrack2
        function CutButtonTrack2Pushed(app, event)
            t = trackTime(app, 2);
            [~,numCols] = size(app.track2_y);
            last = app.CutEndPoint_2;
            start = app.CutStartPoint_2;
            if numCols > 1
                [~,loc1] = min(abs(t-start));
                [~,locx] = min(abs(t-abs(last)));
                app.track2_y([1:loc1,locx:end],:)=[];
                reDrawPlot(app, 2);
                
                %                if the track is mono, then we want to create a 1 column vector to cut
            else
                [~,loc1] = min(abs(t-start));
                [~,locx] = min(abs(t-abs(last)));
                app.track2_y([1:loc1,locx:end])=[];
                reDrawPlot(app, 2);
            end
            app.playerTrack2 = audioplayer(app.track2_y, app.track2_fs);
        end

        % Value changed function: CutStartSpinnerTrackBoth
        function CutStartSpinnerTrackBothValueChanged(app, event)
            value = app.CutStartSpinnerTrackBoth.Value;
            if value < get(app.CutLineEnd_Both, 'xdata')
                set(app.CutLineStart_Both, 'xdata', value*[1,1]);
                drawnow;
                app.CutStartPoint_Both = value;
            end
        end

        % Value changed function: CutEndSpinnerTrackBoth
        function CutEndSpinnerTrackBothValueChanged(app, event)
            value = app.CutEndSpinnerTrackBoth.Value;
            if value > get(app.CutLineStart_Both, 'xdata')
                app.CutEndPoint_Both = value;
                value = get(app.CutLineEnd_Both, 'xdata') - value;
                set(app.CutLineEnd_Both, 'xdata', get(app.CutLineEnd_Both, 'xdata')-value);
                drawnow;
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            fs = get(app.SamplingFrequencyEditField, 'Value');
            bits = str2num(get(app.BitspersampleDropDown, 'Value'));
            channels = str2num(get(app.NumberOfchannelsDropDown, 'Value'));
            app.Recording = audiorecorder(fs, bits, channels);
            set(app.EndRecordingButton, 'Enable', 'on');
            set(app.LampRecording, 'Enable', 'on');
            record(app.Recording);
            
        end

        % Button pushed function: EndRecordingButton
        function EndRecordingButtonPushed(app, event)
            
            set(app.LampRecording, 'Enable', 'off');
            stop(app.Recording);
            samples = getaudiodata(app.Recording);
            app.RecordedPlayer = audioplayer(app.Recording);
            plot(app.UIAxesRecorded, [ 1: size(samples)] / get(app.SamplingFrequencyEditField, 'Value'), samples);
            set(app.PlayRecordingButton, 'Enable', 'on')
        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)
            % player = play(app.Recording);
            play(app.RecordedPlayer);
            %            stop(player);
        end

        % Button pushed function: GetDeviceInfoButton
        function GetDeviceInfoButtonPushed(app, event)
            deviceReader = audioDeviceReader;
            arr= struct2table(info(deviceReader), 'AsArray', true);
            set(app.UITableDeviceInfo, 'Data', arr);
            
        end

        % Button pushed function: ResampleTrack1Button
        function ResampleTrack1ButtonPushed(app, event)
            currentFs = get(app.CurrentSamplingFrequencyTrack1EditField, 'Value');
            newFs = get(app.NewSamplingFrequencyEditTrack1Field, 'Value');
            new_y = resample(app.track1_y, newFs, currentFs);
            app.track1_y = new_y;
            app.track1_fs = newFs;
            reDrawPlot(app, 1);
            set(app.CurrentSamplingFrequencyTrack1EditField, 'Value', newFs);
        end

        % Button pushed function: ExportTrack1Button
        function ExportTrack1ButtonPushed(app, event)
            filename = get(app.FilenameTrack1EditField, 'Value');
            y = app.track1_y/max(abs(app.track1_y(:)));
            audiowrite(filename, y, app.track1_fs);
        end

        % Value changed function: ListBoxHelp
        function ListBoxHelpValueChanged(app, event)
            value = app.ListBoxHelp.Value;
            set(app.HTML, 'HTMLSource', value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SoundEditorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {766, 766};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ResampleTrack2Button
        function ResampleTrack2ButtonPushed(app, event)
            currentFs = get(app.CurrentSamplingFrequencyTrack2EditField, 'Value');
            newFs = get(app.NewSamplingFrequencyEditTrack2Field, 'Value');
            new_y = resample(app.track2_y, newFs, currentFs);
            app.track2_y = new_y;
            app.track2_fs = newFs;
            reDrawPlot(app, 2);
            set(app.CurrentSamplingFrequencyTrack2EditField, 'Value', newFs);
        end

        % Button pushed function: ExportTrack2Button
        function ExportTrack2ButtonPushed(app, event)
            filename = get(app.FilenameTrack2EditField, 'Value');
            y = app.track2_y/max(abs(app.track2_y(:)));
            audiowrite(filename, y, app.track2_fs);
        end

        % Button pushed function: ResampleTrackBothButton
        function ResampleTrackBothButtonPushed(app, event)
            currentFs = get(app.CurrentSamplingFrequencyTrackBothEditField, 'Value');
            newFs = get(app.NewSamplingFrequencyEditTrackBothField, 'Value');
            new_y = resample(app.trackBoth_y, newFs, currentFs);
            app.trackBoth_y = new_y;
            app.trackBoth_fs = newFs;
            reDrawPlot(app, 3);
            set(app.CurrentSamplingFrequencyTrackBothEditField, 'Value', newFs);
        end

        % Button pushed function: ApplyToTrack1Button
        function ApplyToTrack1ButtonPushed(app, event)
            recording = getaudiodata(app.Recording);
            app.track1_y = recording;
            app.track1_fs = get(app.SamplingFrequencyEditField, 'Value');
            app.track1 = 'Recording.wav';
            reDrawPlot(app, 1);
            set(app.PlayButtonTrack1, 'Enable', 'on');
            set(app.CurrentSamplingFrequencyTrack1EditField, 'Value', app.track1_fs);
            set(app.LampTrack1, 'Enable', 'on');
            set(app.OffsetSliderTrack1, 'Enable', 'on');
            set(app.FilenameTrack1EditField, 'Value', app.track1);
            
        end

        % Button pushed function: ApplyToTrack2Button
        function ApplyToTrack2ButtonPushed(app, event)
            recording = getaudiodata(app.Recording);
            app.track2_y = recording;
            app.track2_fs = get(app.SamplingFrequencyEditField, 'Value');
            app.track2 = 'Recording.wav';
            reDrawPlot(app, 2);
            set(app.PlayButtonTrack2, 'Enable', 'on');
            set(app.CurrentSamplingFrequencyTrack2EditField, 'Value', app.track2_fs);
            set(app.LampTrack2, 'Enable', 'on');
            set(app.OffsetSliderTrack2, 'Enable', 'on');
            set(app.FilenameTrack2EditField, 'Value', app.track2);
        end

        % Value changing function: OffsetSliderTrack1
        function OffsetSliderTrack1ValueChanging(app, event)
            value = event.Value;
            %           determine whether the track is mono/stereo
            [numCols] = size(app.track1_y);
            
            %           calculate the time in seconds
            t = trackTime(app, 1);
            end_time = length(app.track1_y)/app.track1_fs;
            
            %           if the value is greater then zero, then we want to pad
            if value > 0
                
                %               if the track is stereo, then we want to create a 2 column vector to pad
                if numCols > 1
                    pad= zeros(round(value*app.track1_fs),2);
                    app.track1_y = [pad ; app.track1_y];
                    app.playerTrack1 = audioplayer(app.track1_y , app.track1_fs);
                    
                    %               if the track is mono then the a one column vector is needed
                else
                    pad= zeros(round(value*app.track1_fs),1);
                    app.track1_y = [pad ; app.track1_y];
                    
                    app.playerTrack1 = audioplayer(app.track1_y , app.track1_fs);
                end
                
                %           if the value is less than 0 we want to remove the a track section,
                %           but don't allow removal of more than the whole track
            elseif (value < 0) && (abs(value) < end_time)
                
                %                if the track is stereo, then we want to create a 2 column vector to cut
                if numCols > 1
                    [~,loc1] = min(abs(t));
                    [~,locx] = min(abs(t-abs(value)));
                    app.track1_y(1:locx,:) =[];
                    app.playerTrack1 = audioplayer(app.track1_y, app.track1_fs);
                    
                    %                if the track is mono, then we want to create a 1 column vector to cut
                else
                    [~,loc1] = min(abs(t));
                    [~,locx] = min(abs(t-abs(value)));
                    app.track1_y(1:locx) =[];
                    app.playerTrack1 = audioplayer(app.track1_y, app.track1_fs);
                end
            end
            
            %           Recalculate the new time, and then plot
            reDrawPlot(app, 1);
            
        end

        % Value changing function: OffsetSliderTrack2
        function OffsetSliderTrack2ValueChanging(app, event)
            value = event.Value;
            %           determine whether the track is mono/stereo
            [numCols] = size(app.track2_y);
            
            %           calculate the time in seconds
            t = trackTime(app, 2);
            end_time = length(app.track2_y)/app.track2_fs;
            
            %           if the value is greater then zero, then we want to pad
            if value > 0
                
                %               if the track is stereo, then we want to create a 2 column vector to pad
                if numCols > 1
                    pad= zeros(round(value*app.track2_fs),2);
                    app.track2_y = [pad ; app.track2_y];
                    app.playerTrack2 = audioplayer(app.track2_y , app.track2_fs);
                    
                    %               if the track is mono then the a one column vector is needed
                else
                    pad= zeros(round(value*app.track2_fs),1);
                    app.track2_y = [pad ; app.track2_y];
                    
                    app.playerTrack2 = audioplayer(app.track2_y , app.track2_fs);
                end
                
                %           if the value is less than 0 we want to remove the a track section,
                %           but don't allow removal of more than the whole track
            elseif (value < 0) && (abs(value) < end_time)
                
                %                if the track is stereo, then we want to create a 2 column vector to cut
                if numCols > 1
                    [~,loc1] = min(abs(t));
                    [~,locx] = min(abs(t-abs(value)));
                    app.track2_y(1:locx,:) =[];
                    app.playerTrack2 = audioplayer(app.track2_y, app.track2_fs);
                    
                    %                if the track is mono, then we want to create a 1 column vector to cut
                else
                    [~,loc1] = min(abs(t));
                    [~,locx] = min(abs(t-abs(value)));
                    app.track2_y(1:locx) =[];
                    app.playerTrack2 = audioplayer(app.track2_y, app.track2_fs);
                end
            end
            
            %           Recalculate the new time, and then plot
            reDrawPlot(app, 2);
        end

        % Button pushed function: CutButtonTrackBoth
        function CutButtonTrackBothPushed(app, event)
            t = trackTime(app, 3);
            [~,numCols] = size(app.trackBoth_y);
            last = app.CutEndPoint_Both;
            start = app.CutStartPoint_Both;
            if numCols > 1
                [~,loc1] = min(abs(t-app.CutStartPoint_Both));
                [~,locx] = min(abs(t-abs(app.CutEndPoint_Both)));
                app.trackBoth_y([1:loc1,locx:end],:)=[];
                reDrawPlot(app, 3);
                
                %                if the track is mono, then we want to create a 1 column vector to cut
            else
                [~,loc1] = min(abs(t-app.CutStartPoint_Both));
                [~,locx] = min(abs(t-abs(app.CutEndPoint_Both)));
                app.trackBoth_y([1:loc1,locx:end])=[];
                reDrawPlot(app, 3);
            end
            app.playerTrackBoth = audioplayer(app.trackBoth_y, app.trackBoth_fs);
        end

        % Button pushed function: EqualiseTrack1Button
        function EqualiseTrack1ButtonPushed(app, event)
            equalise(app, 1, app.track1_fs, app.track1_y);
            
        end

        % Button pushed function: EqualiseTrack2Button
        function EqualiseTrack2ButtonPushed(app, event)
            equalise(app, 2, app.track2_fs, app.track2_y);
            
        end

        % Button pushed function: EqualiseTrackBothButton
        function EqualiseTrackBothButtonPushed(app, event)
            equalise(app, 3, app.trackBoth_fs, app.trackBoth_y);
        end

        % Button pushed function: ExportTrackBothButton
        function ExportTrackBothButtonPushed(app, event)
            filename = get(app.FilenameTrackBothEditField, 'Value');
            y = app.trackBoth_y/max(abs(app.trackBoth_y(:)));
            audiowrite(filename, y, app.trackBoth_fs);
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            ydft = fft(app.track1_y);
            ydft = ydft(1:length(app.track1_y)/2+1);
            freq = 0:app.track1_fs/length(app.track1_y):app.track1_fs/2;
            plot(app.UIAxesEqualiser_2, freq, abs(ydft));
            plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
        end

        % Button pushed function: SpectrumButton_2
        function SpectrumButton_2Pushed(app, event)
            ydft = fft(app.trackBoth_y);
            ydft = ydft(1:length(app.trackBoth_y)/2+1);
            freq = 0:app.trackBoth_fs/length(app.trackBoth_y):app.trackBoth_fs/2;
            plot(app.UIAxesEqualiser_2, freq, abs(ydft));
            plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
        end

        % Button pushed function: SpectrumButton_3
        function SpectrumButton_3Pushed(app, event)
            ydft = fft(app.track2_y);
            ydft = ydft(1:length(app.track2_y)/2+1);
            freq = 0:app.track2_fs/length(app.track2_y):app.track2_fs/2;
            plot(app.UIAxesEqualiser_2, freq, abs(ydft));
            plot(app.UIAxesEqualiser,freq,unwrap(angle(ydft)));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            set(app.HzSlider, 'Value', 0);
            set(app.HzSlider_2, 'Value', 0);
            set(app.HzSlider_3, 'Value', 0);
            set(app.HzSlider_4, 'Value', 0);
            set(app.KHzSlider, 'Value', 0);
            set(app.KHzSlider_2, 'Value', 0);
            set(app.KHzSlider_3, 'Value', 0);
            set(app.KHzSlider_4, 'Value', 0);
            set(app.KHzSlider_5, 'Value', 0);
            set(app.KHzSlider_6, 'Value',0);
            set(app.VolumeSlider, 'Value', 5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoundEditorUIFigure = uifigure('Visible', 'off');
            app.SoundEditorUIFigure.Name = 'Sound Editor';
            app.SoundEditorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SoundEditorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Track1Panel = uipanel(app.GridLayout2);
            app.Track1Panel.Title = 'Track 1';
            app.PlayButtonTrack1 = uibutton(app.Track1Panel, 'push');
            app.PlayButtonTrack1.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonTrack1Pushed, true);
            app.PlayButtonTrack1.Enable = 'off';
            app.PlayButtonTrack1.Text = 'Play';
            app.volumeGaugeLabel = uilabel(app.Track1Panel);
            app.volumeGaugeLabel.Text = 'volume';
            app.volumeGaugeTrack1 = uigauge(app.Track1Panel, 'linear');
            app.volumeGaugeTrack1.Limits = [0 200];
            app.volumeGaugeTrack1.MinorTicks = [];
            app.EditFieldVolumeTrack1 = uieditfield(app.Track1Panel, 'numeric');
            app.EditFieldVolumeTrack1.RoundFractionalValues = 'on';
            app.EditFieldVolumeTrack1.ValueDisplayFormat = '%.3f';
            app.volumeadjustLabel = uilabel(app.Track1Panel);
            app.volumeadjustLabel.Text = 'volume adjust';
            app.volumeKnobTrack1 = uiknob(app.Track1Panel, 'continuous');
            app.volumeKnobTrack1.Limits = [0.1 2];
            app.volumeKnobTrack1.ValueChangedFcn = createCallbackFcn(app, @volumeKnobTrack1ValueChanged, true);
            app.volumeKnobTrack1.Tooltip = {'Adjust the volume by the factor of selected number. Adjusts both channels.'};
            app.volumeKnobTrack1.Value = 1;
            app.PauseButtonTrack1 = uibutton(app.Track1Panel, 'push');
            app.PauseButtonTrack1.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonTrack1Pushed, true);
            app.PauseButtonTrack1.Enable = 'off';
            app.PauseButtonTrack1.Text = 'Pause';
            app.StopButtonTrack1 = uibutton(app.Track1Panel, 'push');
            app.StopButtonTrack1.ButtonPushedFcn = createCallbackFcn(app, @StopButtonTrack1Pushed, true);
            app.StopButtonTrack1.Enable = 'off';
            app.StopButtonTrack1.Text = 'Stop';
            app.LampTrack1 = uilamp(app.Track1Panel);
            app.LampTrack1.Enable = 'off';
            app.MainPanel = uipanel(app.GridLayout2);
            app.MainPanel.Title = 'Main Panel';
            app.Browse = uibutton(app.MainPanel, 'push');
            app.Browse.ButtonPushedFcn = createCallbackFcn(app, @BrowsePushed, true);
            app.Browse.Text = 'Load';
            app.LoadedSoundsListBoxLabel = uilabel(app.MainPanel);
            app.LoadedSoundsListBoxLabel.Text = 'Loaded Sounds';
            app.LoadedSoundsListBox = uilistbox(app.MainPanel);
            app.LoadedSoundsListBox.Items = {};
            app.LoadedSoundsListBox.Value = {};
            app.LoadTrack1 = uibutton(app.MainPanel, 'push');
            app.LoadTrack1.ButtonPushedFcn = createCallbackFcn(app, @LoadTrack1ButtonPushed, true);
            app.LoadTrack1.Enable = 'off';
            app.LoadTrack1.Text = '↑';
            app.LoadTrack2 = uibutton(app.MainPanel, 'push');
            app.LoadTrack2.ButtonPushedFcn = createCallbackFcn(app, @LoadTrack2ButtonPushed, true);
            app.LoadTrack2.Enable = 'off';
            app.LoadTrack2.Text = '↓';
            app.PlayButtonBoth = uibutton(app.MainPanel, 'push');
            app.PlayButtonBoth.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonBothPushed, true);
            app.PlayButtonBoth.Tooltip = {'Once two tracks are loaded, click play to merge and play the resulting sound.'};
            app.PlayButtonBoth.Text = 'Play';
            app.PauseButtonBoth = uibutton(app.MainPanel, 'push');
            app.PauseButtonBoth.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonBothPushed, true);
            app.PauseButtonBoth.Enable = 'off';
            app.PauseButtonBoth.Text = 'Pause';
            app.StopButtonBoth = uibutton(app.MainPanel, 'push');
            app.StopButtonBoth.ButtonPushedFcn = createCallbackFcn(app, @StopButtonBothPushed, true);
            app.StopButtonBoth.Enable = 'off';
            app.StopButtonBoth.Text = 'Stop';
            app.Track2Panel_2 = uipanel(app.GridLayout2);
            app.Track2Panel_2.Title = 'Track 2';
            app.PlayButtonTrack2 = uibutton(app.Track2Panel_2, 'push');
            app.PlayButtonTrack2.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonTrack2Pushed, true);
            app.PlayButtonTrack2.Enable = 'off';
            app.PlayButtonTrack2.Text = 'Play';
            app.volumeadjustLabel_2 = uilabel(app.Track2Panel_2);
            app.volumeadjustLabel_2.Text = 'volume adjust';
            app.volumeKnobTrack2 = uiknob(app.Track2Panel_2, 'continuous');
            app.volumeKnobTrack2.Limits = [0.1 2];
            app.volumeKnobTrack2.ValueChangedFcn = createCallbackFcn(app, @volumeKnobTrack2ValueChanged, true);
            app.volumeKnobTrack2.Tooltip = {'Adjust the volume by the factor of selected number. Adjusts both channels.'};
            app.volumeKnobTrack2.Value = 1;
            app.PauseButtonTrack2 = uibutton(app.Track2Panel_2, 'push');
            app.PauseButtonTrack2.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonTrack2Pushed, true);
            app.PauseButtonTrack2.Enable = 'off';
            app.PauseButtonTrack2.Text = 'Pause';
            app.StopButtonTrack2 = uibutton(app.Track2Panel_2, 'push');
            app.StopButtonTrack2.ButtonPushedFcn = createCallbackFcn(app, @StopButtonTrack2Pushed, true);
            app.StopButtonTrack2.Enable = 'off';
            app.StopButtonTrack2.Text = 'Stop';
            app.volumeGaugeLabel_2 = uilabel(app.Track2Panel_2);
            app.volumeGaugeLabel_2.Text = 'volume';
            app.volumeGaugeTrack2 = uigauge(app.Track2Panel_2, 'linear');
            app.volumeGaugeTrack2.Limits = [-96 0];
            app.volumeGaugeTrack2.MinorTicks = [];
            app.volumeGaugeTrack2.Value = -96;
            app.EditFieldVolumeTrack2 = uieditfield(app.Track2Panel_2, 'numeric');
            app.EditFieldVolumeTrack2.RoundFractionalValues = 'on';
            app.EditFieldVolumeTrack2.ValueDisplayFormat = '%11.2g';
            app.LampTrack2 = uilamp(app.Track2Panel_2);
            app.LampTrack2.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.TabGroup = uitabgroup(app.Panel);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout3 = uigridlayout(app.MainTab);
            app.Track1Panel_2 = uipanel(app.GridLayout3);
            app.Track1Panel_2.Title = 'Track 1';
            app.UIAxesTrack1 = uiaxes(app.Track1Panel_2);
            xlabel(app.UIAxesTrack1, 'Seconds')
            ylabel(app.UIAxesTrack1, 'Amplitude')
            app.OffsetSliderLabel = uilabel(app.Track1Panel_2);
            app.OffsetSliderLabel.Enable = 'off';
            app.OffsetSliderLabel.Text = 'Offset';
            app.OffsetSliderTrack1 = uislider(app.Track1Panel_2);
            app.OffsetSliderTrack1.Limits = [-2 2];
            app.OffsetSliderTrack1.ValueChangingFcn = createCallbackFcn(app, @OffsetSliderTrack1ValueChanging, true);
            app.OffsetSliderTrack1.Enable = 'off';
            app.Track2Panel = uipanel(app.GridLayout3);
            app.Track2Panel.Title = 'Track 2';
            app.UIAxesTrack2 = uiaxes(app.Track2Panel);
            xlabel(app.UIAxesTrack2, 'Seconds')
            ylabel(app.UIAxesTrack2, 'Amplitude')
            app.OffsetSliderLabel_2 = uilabel(app.Track2Panel);
            app.OffsetSliderLabel_2.Enable = 'off';
            app.OffsetSliderLabel_2.Text = 'Offset';
            app.OffsetSliderTrack2 = uislider(app.Track2Panel);
            app.OffsetSliderTrack2.Limits = [-2 2];
            app.OffsetSliderTrack2.ValueChangingFcn = createCallbackFcn(app, @OffsetSliderTrack2ValueChanging, true);
            app.OffsetSliderTrack2.Enable = 'off';
            app.BothPanel_2 = uipanel(app.GridLayout3);
            app.BothPanel_2.Title = 'Both';
            app.UIAxesTrackBoth = uiaxes(app.BothPanel_2);
            xlabel(app.UIAxesTrackBoth, 'Seconds')
            ylabel(app.UIAxesTrackBoth, 'Amplitude')
            app.CuttingTab = uitab(app.TabGroup);
            app.CuttingTab.Title = 'Cutting';
            app.GridLayout8 = uigridlayout(app.CuttingTab);
            app.Track1Panel_4 = uipanel(app.GridLayout8);
            app.Track1Panel_4.Title = 'Track1';
            app.UIAxesTrack1_Cut = uiaxes(app.Track1Panel_4);
            app.StartSpinnerLabel = uilabel(app.Track1Panel_4);
            app.StartSpinnerLabel.Text = 'Start';
            app.CutStartSpinnerTrack1 = uispinner(app.Track1Panel_4);
            app.CutStartSpinnerTrack1.Step = 0.1;
            app.CutStartSpinnerTrack1.Limits = [0 Inf];
            app.CutStartSpinnerTrack1.ValueDisplayFormat = '%.1f';
            app.CutStartSpinnerTrack1.ValueChangedFcn = createCallbackFcn(app, @CutStartSpinnerTrack1ValueChanged, true);
            app.CutStartSpinnerTrack1.Tooltip = {'Cut the specified seconds from the start'};
            app.EndLabel = uilabel(app.Track1Panel_4);
            app.EndLabel.Text = 'End';
            app.CutEndSpinnerTrack1 = uispinner(app.Track1Panel_4);
            app.CutEndSpinnerTrack1.Step = 0.1;
            app.CutEndSpinnerTrack1.Limits = [-Inf 0];
            app.CutEndSpinnerTrack1.ValueDisplayFormat = '%.1f';
            app.CutEndSpinnerTrack1.ValueChangedFcn = createCallbackFcn(app, @CutEndSpinnerTrack1ValueChanged, true);
            app.CutEndSpinnerTrack1.Tooltip = {'Cut the specified seconds from the end'};
            app.CutButtonTrack1 = uibutton(app.Track1Panel_4, 'push');
            app.CutButtonTrack1.ButtonPushedFcn = createCallbackFcn(app, @CutButtonTrack1Pushed, true);
            app.CutButtonTrack1.Tooltip = {'Apply the cut'};
            app.CutButtonTrack1.Text = 'Cut';
            app.Track2Panel_4 = uipanel(app.GridLayout8);
            app.Track2Panel_4.Title = 'Track2';
            app.UIAxesTrack2_Cut = uiaxes(app.Track2Panel_4);
            app.CutButtonTrack2 = uibutton(app.Track2Panel_4, 'push');
            app.CutButtonTrack2.ButtonPushedFcn = createCallbackFcn(app, @CutButtonTrack2Pushed, true);
            app.CutButtonTrack2.Text = 'Cut';
            app.StartSpinnerLabel_2 = uilabel(app.Track2Panel_4);
            app.StartSpinnerLabel_2.Text = 'Start';
            app.CutStartSpinnerTrack2 = uispinner(app.Track2Panel_4);
            app.CutStartSpinnerTrack2.Step = 0.11;
            app.CutStartSpinnerTrack2.Limits = [0 Inf];
            app.CutStartSpinnerTrack2.ValueDisplayFormat = '%.1f';
            app.CutStartSpinnerTrack2.ValueChangedFcn = createCallbackFcn(app, @CutStartSpinnerTrack2ValueChanged, true);
            app.EndLabel_2 = uilabel(app.Track2Panel_4);
            app.EndLabel_2.Text = 'End';
            app.CutEndSpinnerTrack2 = uispinner(app.Track2Panel_4);
            app.CutEndSpinnerTrack2.Step = 0.1;
            app.CutEndSpinnerTrack2.Limits = [-Inf 0];
            app.CutEndSpinnerTrack2.ValueDisplayFormat = '%.1f';
            app.CutEndSpinnerTrack2.ValueChangedFcn = createCallbackFcn(app, @CutEndSpinnerTrack2ValueChanged, true);
            app.BothPanel_3 = uipanel(app.GridLayout8);
            app.BothPanel_3.Title = 'Both';
            app.UIAxesTrackBoth_Cut = uiaxes(app.BothPanel_3);
            app.StartSpinnerLabel_3 = uilabel(app.BothPanel_3);
            app.StartSpinnerLabel_3.Text = 'Start';
            app.CutStartSpinnerTrackBoth = uispinner(app.BothPanel_3);
            app.CutStartSpinnerTrackBoth.Step = 0.1;
            app.CutStartSpinnerTrackBoth.Limits = [0 Inf];
            app.CutStartSpinnerTrackBoth.ValueDisplayFormat = '%.1f';
            app.CutStartSpinnerTrackBoth.ValueChangedFcn = createCallbackFcn(app, @CutStartSpinnerTrackBothValueChanged, true);
            app.CutStartSpinnerTrackBoth.Tooltip = {'Cut the specified seconds from the start'};
            app.EndLabel_3 = uilabel(app.BothPanel_3);
            app.EndLabel_3.Text = 'End';
            app.CutEndSpinnerTrackBoth = uispinner(app.BothPanel_3);
            app.CutEndSpinnerTrackBoth.Step = 0.1;
            app.CutEndSpinnerTrackBoth.Limits = [-Inf 0];
            app.CutEndSpinnerTrackBoth.ValueDisplayFormat = '%.1f';
            app.CutEndSpinnerTrackBoth.ValueChangedFcn = createCallbackFcn(app, @CutEndSpinnerTrackBothValueChanged, true);
            app.CutEndSpinnerTrackBoth.Tooltip = {'Cut the specified seconds from the end'};
            app.CutButtonTrackBoth = uibutton(app.BothPanel_3, 'push');
            app.CutButtonTrackBoth.ButtonPushedFcn = createCallbackFcn(app, @CutButtonTrackBothPushed, true);
            app.CutButtonTrackBoth.Tooltip = {'Apply the cut'};
            app.CutButtonTrackBoth.Text = 'Cut';
            app.EqualiserTab = uitab(app.TabGroup);
            app.EqualiserTab.Title = 'Equaliser';
            app.GridLayout14 = uigridlayout(app.EqualiserTab);
            app.ControlsPanel = uipanel(app.GridLayout14);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout15 = uigridlayout(app.ControlsPanel);
            app.HzSliderLabel = uilabel(app.GridLayout15);
            app.HzSliderLabel.Text = '0-200Hz';
            app.HzSlider = uislider(app.GridLayout15);
            app.HzSlider.Limits = [-15 15];
            app.HzLabel = uilabel(app.GridLayout15);
            app.HzLabel.Text = '201-400Hz';
            app.HzSlider_2 = uislider(app.GridLayout15);
            app.HzSlider_2.Limits = [-15 15];
            app.HzLabel_2 = uilabel(app.GridLayout15);
            app.HzLabel_2.Text = '401-800Hz';
            app.HzSlider_3 = uislider(app.GridLayout15);
            app.HzSlider_3.Limits = [-15 15];
            app.HzLabel_3 = uilabel(app.GridLayout15);
            app.HzLabel_3.Text = '801-1500Hz';
            app.HzSlider_4 = uislider(app.GridLayout15);
            app.HzSlider_4.Limits = [-15 15];
            app.KHzSliderLabel = uilabel(app.GridLayout15);
            app.KHzSliderLabel.Text = '1.5-3KHz';
            app.KHzSlider = uislider(app.GridLayout15);
            app.KHzSlider.Limits = [-15 15];
            app.KHzSlider_2Label = uilabel(app.GridLayout15);
            app.KHzSlider_2Label.Text = '3-5KHz';
            app.KHzSlider_2 = uislider(app.GridLayout15);
            app.KHzSlider_2.Limits = [-15 15];
            app.KHzSlider_3Label = uilabel(app.GridLayout15);
            app.KHzSlider_3Label.Text = '5-7KHz';
            app.KHzSlider_3 = uislider(app.GridLayout15);
            app.KHzSlider_3.Limits = [-15 15];
            app.KHzSlider_4Label = uilabel(app.GridLayout15);
            app.KHzSlider_4Label.Text = '7-10KHz';
            app.KHzSlider_4 = uislider(app.GridLayout15);
            app.KHzSlider_4.Limits = [-15 15];
            app.KHzSlider_5Label = uilabel(app.GridLayout15);
            app.KHzSlider_5Label.Text = '10-15KHz';
            app.KHzSlider_5 = uislider(app.GridLayout15);
            app.KHzSlider_5.Limits = [-15 15];
            app.KHzSlider_6Label = uilabel(app.GridLayout15);
            app.KHzSlider_6Label.Text = '>15KHz';
            app.KHzSlider_6 = uislider(app.GridLayout15);
            app.KHzSlider_6.Limits = [-15 15];
            app.GraphPanel = uipanel(app.GridLayout14);
            app.GraphPanel.Title = 'Graph';
            app.UIAxesEqualiser = uiaxes(app.GraphPanel);
            title(app.UIAxesEqualiser, 'Phase')
            xlabel(app.UIAxesEqualiser, 'Hz')
            ylabel(app.UIAxesEqualiser, '')
            app.EqualiseTrack1Button = uibutton(app.GraphPanel, 'push');
            app.EqualiseTrack1Button.ButtonPushedFcn = createCallbackFcn(app, @EqualiseTrack1ButtonPushed, true);
            app.EqualiseTrack1Button.Text = 'Equalise Track1';
            app.EqualiseTrack2Button = uibutton(app.GraphPanel, 'push');
            app.EqualiseTrack2Button.ButtonPushedFcn = createCallbackFcn(app, @EqualiseTrack2ButtonPushed, true);
            app.EqualiseTrack2Button.Text = 'Equalise Track 2';
            app.EqualiseTrackBothButton = uibutton(app.GraphPanel, 'push');
            app.EqualiseTrackBothButton.ButtonPushedFcn = createCallbackFcn(app, @EqualiseTrackBothButtonPushed, true);
            app.EqualiseTrackBothButton.Text = 'Equalise Track Both';
            app.SpectrumButton = uibutton(app.GraphPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.SpectrumButton_2 = uibutton(app.GraphPanel, 'push');
            app.SpectrumButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButton_2Pushed, true);
            app.SpectrumButton_2.Text = 'Spectrum';
            app.SpectrumButton_3 = uibutton(app.GraphPanel, 'push');
            app.SpectrumButton_3.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButton_3Pushed, true);
            app.SpectrumButton_3.Text = 'Spectrum';
            app.UIAxesEqualiser_2 = uiaxes(app.GraphPanel);
            title(app.UIAxesEqualiser_2, 'Magnitude')
            xlabel(app.UIAxesEqualiser_2, '')
            ylabel(app.UIAxesEqualiser_2, '')
            app.VolumeSliderLabel = uilabel(app.GraphPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.GraphPanel);
            app.VolumeSlider.Limits = [0 10];
            app.VolumeSlider.Value = 5;
            app.ResetButton = uibutton(app.GraphPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.GridLayout9 = uigridlayout(app.RecordTab);
            app.Panel_3 = uipanel(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.Panel_3);
            app.RecordingoptionsPanel = uipanel(app.GridLayout10);
            app.RecordingoptionsPanel.Title = 'Recording options';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.RecordingoptionsPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.RecordingoptionsPanel, 'numeric');
            app.SamplingFrequencyEditField.Limits = [0 Inf];
            app.SamplingFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.SamplingFrequencyEditField.Value = 44100;
            app.BitspersampleDropDownLabel = uilabel(app.RecordingoptionsPanel);
            app.BitspersampleDropDownLabel.Text = {'Bits per sample'; ''};
            app.BitspersampleDropDown = uidropdown(app.RecordingoptionsPanel);
            app.BitspersampleDropDown.Items = {'8', '16', '24'};
            app.BitspersampleDropDown.Value = '8';
            app.NumberOfchannelsDropDownLabel = uilabel(app.RecordingoptionsPanel);
            app.NumberOfchannelsDropDownLabel.Text = {'Number Of channels'; ''};
            app.NumberOfchannelsDropDown = uidropdown(app.RecordingoptionsPanel);
            app.NumberOfchannelsDropDown.Items = {'1', '2'};
            app.NumberOfchannelsDropDown.Value = '1';
            app.GetDeviceInfoButton = uibutton(app.RecordingoptionsPanel, 'push');
            app.GetDeviceInfoButton.ButtonPushedFcn = createCallbackFcn(app, @GetDeviceInfoButtonPushed, true);
            app.GetDeviceInfoButton.Text = 'Get Device Info';
            app.UITableDeviceInfo = uitable(app.RecordingoptionsPanel);
            app.UITableDeviceInfo.ColumnName = {'Driver'; 'DeviceName'; 'No. Supported Channels'};
            app.UITableDeviceInfo.RowName = {};
            app.Panel_2 = uipanel(app.GridLayout10);
            app.StartRecordingButton = uibutton(app.Panel_2, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PlayRecordingButton = uibutton(app.Panel_2, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Enable = 'off';
            app.PlayRecordingButton.Text = 'Play Recording';
            app.EndRecordingButton = uibutton(app.Panel_2, 'push');
            app.EndRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @EndRecordingButtonPushed, true);
            app.EndRecordingButton.Enable = 'off';
            app.EndRecordingButton.Text = 'End Recording';
            app.LampRecording = uilamp(app.Panel_2);
            app.LampRecording.Enable = 'off';
            app.RecordedAudioPanel = uipanel(app.GridLayout9);
            app.RecordedAudioPanel.Title = 'Recorded Audio';
            app.UIAxesRecorded = uiaxes(app.RecordedAudioPanel);
            title(app.UIAxesRecorded, '')
            xlabel(app.UIAxesRecorded, 'Seconds')
            ylabel(app.UIAxesRecorded, 'Amplitude')
            app.ApplyToTrack1Button = uibutton(app.RecordedAudioPanel, 'push');
            app.ApplyToTrack1Button.ButtonPushedFcn = createCallbackFcn(app, @ApplyToTrack1ButtonPushed, true);
            app.ApplyToTrack1Button.Text = 'Apply To Track 1';
            app.ApplyToTrack2Button = uibutton(app.RecordedAudioPanel, 'push');
            app.ApplyToTrack2Button.ButtonPushedFcn = createCallbackFcn(app, @ApplyToTrack2ButtonPushed, true);
            app.ApplyToTrack2Button.Text = 'Apply To Track 2';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.GridLayout11 = uigridlayout(app.ExportTab);
            app.SettingsPanel = uipanel(app.GridLayout11);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout12 = uigridlayout(app.SettingsPanel);
            app.Track1Panel_5 = uipanel(app.GridLayout12);
            app.Track1Panel_5.Title = 'Track 1';
            app.FilenameEditFieldLabel = uilabel(app.Track1Panel_5);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameTrack1EditField = uieditfield(app.Track1Panel_5, 'text');
            app.CurrentSamplingFrequencyEditFieldLabel = uilabel(app.Track1Panel_5);
            app.CurrentSamplingFrequencyEditFieldLabel.Text = 'Current Sampling Frequency';
            app.CurrentSamplingFrequencyTrack1EditField = uieditfield(app.Track1Panel_5, 'numeric');
            app.CurrentSamplingFrequencyTrack1EditField.Limits = [0 Inf];
            app.CurrentSamplingFrequencyTrack1EditField.ValueDisplayFormat = '%.0f';
            app.CurrentSamplingFrequencyTrack1EditField.Editable = 'off';
            app.NewSamplingFrequencyEditFieldLabel = uilabel(app.Track1Panel_5);
            app.NewSamplingFrequencyEditFieldLabel.Text = 'New Sampling Frequency';
            app.NewSamplingFrequencyEditTrack1Field = uieditfield(app.Track1Panel_5, 'numeric');
            app.NewSamplingFrequencyEditTrack1Field.Limits = [0 Inf];
            app.NewSamplingFrequencyEditTrack1Field.ValueDisplayFormat = '%.0f';
            app.ResampleTrack1Button = uibutton(app.Track1Panel_5, 'push');
            app.ResampleTrack1Button.ButtonPushedFcn = createCallbackFcn(app, @ResampleTrack1ButtonPushed, true);
            app.ResampleTrack1Button.Text = 'Resample';
            app.ExportTrack1Button = uibutton(app.Track1Panel_5, 'push');
            app.ExportTrack1Button.ButtonPushedFcn = createCallbackFcn(app, @ExportTrack1ButtonPushed, true);
            app.ExportTrack1Button.Text = 'Export';
            app.Track2Panel_5 = uipanel(app.GridLayout12);
            app.Track2Panel_5.Title = 'Track 2';
            app.ResampleTrack2Button = uibutton(app.Track2Panel_5, 'push');
            app.ResampleTrack2Button.ButtonPushedFcn = createCallbackFcn(app, @ResampleTrack2ButtonPushed, true);
            app.ResampleTrack2Button.Text = 'Resample';
            app.ExportTrack2Button = uibutton(app.Track2Panel_5, 'push');
            app.ExportTrack2Button.ButtonPushedFcn = createCallbackFcn(app, @ExportTrack2ButtonPushed, true);
            app.ExportTrack2Button.Text = 'Export';
            app.FilenameEditFieldLabel_2 = uilabel(app.Track2Panel_5);
            app.FilenameEditFieldLabel_2.Text = 'File name';
            app.FilenameTrack2EditField = uieditfield(app.Track2Panel_5, 'text');
            app.CurrentSamplingFrequencyEditFieldLabel_2 = uilabel(app.Track2Panel_5);
            app.CurrentSamplingFrequencyEditFieldLabel_2.Text = 'Current Sampling Frequency';
            app.CurrentSamplingFrequencyTrack2EditField = uieditfield(app.Track2Panel_5, 'numeric');
            app.CurrentSamplingFrequencyTrack2EditField.Limits = [0 Inf];
            app.CurrentSamplingFrequencyTrack2EditField.ValueDisplayFormat = '%.0f';
            app.CurrentSamplingFrequencyTrack2EditField.Editable = 'off';
            app.NewSamplingFrequencyEditFieldLabel_2 = uilabel(app.Track2Panel_5);
            app.NewSamplingFrequencyEditFieldLabel_2.Text = 'New Sampling Frequency';
            app.NewSamplingFrequencyEditTrack2Field = uieditfield(app.Track2Panel_5, 'numeric');
            app.NewSamplingFrequencyEditTrack2Field.Limits = [0 Inf];
            app.NewSamplingFrequencyEditTrack2Field.ValueDisplayFormat = '%.0f';
            app.BothPanel_4 = uipanel(app.GridLayout12);
            app.BothPanel_4.Title = 'Both';
            app.ResampleTrackBothButton = uibutton(app.BothPanel_4, 'push');
            app.ResampleTrackBothButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleTrackBothButtonPushed, true);
            app.ResampleTrackBothButton.Text = 'Resample';
            app.ExportTrackBothButton = uibutton(app.BothPanel_4, 'push');
            app.ExportTrackBothButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTrackBothButtonPushed, true);
            app.ExportTrackBothButton.Text = 'Export';
            app.FilenameEditFieldLabel_3 = uilabel(app.BothPanel_4);
            app.FilenameEditFieldLabel_3.Text = 'File name';
            app.FilenameTrackBothEditField = uieditfield(app.BothPanel_4, 'text');
            app.CurrentSamplingFrequencyEditFieldLabel_3 = uilabel(app.BothPanel_4);
            app.CurrentSamplingFrequencyEditFieldLabel_3.Text = 'Current Sampling Frequency';
            app.CurrentSamplingFrequencyTrackBothEditField = uieditfield(app.BothPanel_4, 'numeric');
            app.CurrentSamplingFrequencyTrackBothEditField.Limits = [0 Inf];
            app.CurrentSamplingFrequencyTrackBothEditField.ValueDisplayFormat = '%.0f';
            app.CurrentSamplingFrequencyTrackBothEditField.Editable = 'off';
            app.NewSamplingFrequencyEditFieldLabel_3 = uilabel(app.BothPanel_4);
            app.NewSamplingFrequencyEditFieldLabel_3.Text = 'New Sampling Frequency';
            app.NewSamplingFrequencyEditTrackBothField = uieditfield(app.BothPanel_4, 'numeric');
            app.NewSamplingFrequencyEditTrackBothField.Limits = [0 Inf];
            app.NewSamplingFrequencyEditTrackBothField.ValueDisplayFormat = '%.0f';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HelpPanel = uipanel(app.HelpTab);
            app.GridLayout7 = uigridlayout(app.HelpPanel);
            app.Panel_4 = uipanel(app.GridLayout7);
            app.ListBoxHelp = uilistbox(app.Panel_4);
            app.ListBoxHelp.ValueChangedFcn = createCallbackFcn(app, @ListBoxHelpValueChanged, true);
            app.Panel_5 = uipanel(app.GridLayout7);
            app.HTML = uihtml(app.Panel_5);
            % Show the figure after all components are created
            app.SoundEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoundEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoundEditorUIFigure)
        end
    end
end